classdef GEWinspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GEWinspectorUIFigure    matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        DropDownPlotType        matlab.ui.control.DropDown
        animateSwitch           matlab.ui.control.Switch
        animateSwitchLabel      matlab.ui.control.Label
        DropDownModeField       matlab.ui.control.DropDown
        plotDropDown_2Label     matlab.ui.control.Label
        DropDownDispersionPlot  matlab.ui.control.DropDown
        plotDropDownLabel       matlab.ui.control.Label
        ModeUIAxes              matlab.ui.control.UIAxes
        DispersionUIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dat  GEWdat   % Description
        modeIdx = []  % mode index
        mode GEWdat   % selected mode's data
        modeField     % field of the selected mode interpolated to plotting grid
        hDisp matlab.graphics.chart.primitive.Line   % graphics handles of the dispersion plot
        hMode                % graphics handles of the plotted mode's field
        dcm                  % datacursormode: data tip to select the mode in the dispersion diagram
        plotDelayTimer timer % only plot after the data tip dragging has finished
        AnimationTimer timer % to animate the mode plot at a fixed rate
        fieldVariablenameList string % variable names of the fields: u, T, p...
        fieldFunctionList cell       % function that computes the desired field: stress(), poyntingVec()...
        componentNames               % names of the field's components, i.e., x, y, z, xx, xy, ...
        time = 0                     % time at which to display the field: stepped during animation
    end
    
    methods (Access = private)

        function selectMode(app, datIdx, sel)
            app.modeIdx.datIdx = datIdx;
            app.modeIdx.sel = sel; 
            dati = app.dat(datIdx);
            [app.modeIdx.i, app.modeIdx.j] = ind2sub(size(dati.k), sel);
            app.mode = extractModes(dati, app.modeIdx.i, app.modeIdx.j);
            app.time = 0; % reset time
        end

        function markMode(app)
            % Mark data tip in dispersion graph. Only needed when re-plotting
            % dispersion with an existing data tip. 
            if ~isempty(app.modeIdx) & ~isempty(app.hDisp) & isvalid(app.hDisp(app.modeIdx.datIdx))
                sel = app.modeIdx.sel;
                datIdx = app.modeIdx.datIdx;
                line = app.hDisp(datIdx); % indexes of dat correspond to indexes of the line handles
                datatip(line,'DataIndex',sel);
            end
        end

        function plotDispersionWavenumbersComplex(app)
            ax = app.DispersionUIAxes; 
            scaleKr = app.dat(1).gew.h; 
            stringKr = '$\Re k h$';
            scaleKi = app.dat(1).gew.h; 
            stringKi = '$\Im k h$';
            scaleW  = app.dat(1).gew.h/app.dat(1).gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';

            for datIdx = 1:length(app.dat)
                dati = app.dat(datIdx);
                W  = real(dati.w)*scaleW; 
                Kr = real(dati.k)*scaleKr;
                Ki = imag(dati.k)*scaleKi;
                h = plot3(ax, Kr(:), Ki(:), W(:),'.', 'SeriesIndex',datIdx,'DisplayName',app.dat(datIdx).gew.family);
                app.hDisp(datIdx) = h;
            end
            % ax.XLim = [min(Kr(:)), max(Kr(:))+eps];
            % ax.YLim = [min(Ki(:)), max(Ki(:))+eps]/20;
            % ax.ZLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringKr; ax.XLabel.Interpreter = 'latex';
            ax.YLabel.String = stringKi; ax.YLabel.Interpreter = 'latex';
            ax.ZLabel.String = stringW;  ax.ZLabel.Interpreter = 'latex';
            view(ax,-23,13);
            legend(ax); legend(ax,'Location','southeast','Interpreter','latex');

            selectMode(app, app.modeIdx.datIdx, app.modeIdx.sel)
            markMode(app); % mark mode
        end
        
        function plotDispersionWavenumbers(app)
            ax = app.DispersionUIAxes; 
            scaleKr = app.dat(1).gew.h; 
            stringKr = '$\Re k h$';
            scaleW  = app.dat(1).gew.h/app.dat(1).gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';

            for datIdx = 1:length(app.dat)
                dati = app.dat(datIdx);
                W  = real(dati.w)*scaleW; 
                Kr = real(dati.k)*scaleKr;
                h = plot(ax, Kr(:), W(:), '.', 'SeriesIndex', datIdx, 'DisplayName',app.dat(datIdx).gew.family);
                app.hDisp(datIdx) = h; 
            end
            % ax.XLim = [min(Kr(:)), max(Kr(:))+eps];
            % ax.YLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringKr; ax.XLabel.Interpreter = 'latex';
            ax.YLabel.String = stringW;  ax.YLabel.Interpreter = 'latex';
            legend(ax); legend(ax,'Location','southeast','Interpreter','latex');

            selectMode(app, app.modeIdx.datIdx, app.modeIdx.sel)
            markMode(app); % mark mode
        end

        function plotDispersionPhasevel(app)
            ax = app.DispersionUIAxes; 
            scaleW = app.dat(1).gew.h/app.dat(1).gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';
            scaleCp = 1/app.dat(1).gew.lay{1}.mat.ct;
            stringCp = '$c_\mathrm{p}/c_\mathrm{t} = (\omega / \Re k)/c_\mathrm{t}$';

            for datIdx = 1:length(app.dat)
                dati = app.dat(datIdx);
                W = real(dati.w)*scaleW; 
                Cp = real(dati.w)./real(dati.k)*scaleCp;
                h = plot(ax, W(:), Cp(:), '.', 'SeriesIndex', datIdx, 'DisplayName',app.dat(datIdx).gew.family);
                app.hDisp(datIdx) = h; 
            end
            ax.YLim = [0, app.dat(1).gew.lay{1}.mat.cl*3]*scaleCp;
            % ax.XLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringW;  ax.XLabel.Interpreter = 'latex';
            ax.YLabel.String = stringCp; ax.YLabel.Interpreter = 'latex';
            legend(ax); legend(ax,'Location','southeast','Interpreter','latex');

            selectMode(app, app.modeIdx.datIdx, app.modeIdx.sel)
            markMode(app); % mark mode
        end

        function plotDispersionEnergyvelAxial(app)
            ax = app.DispersionUIAxes; 
            scaleW = app.dat(1).gew.h/app.dat(1).gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';
            scaleCe = 1/app.dat(1).gew.lay{1}.mat.ct;
            stringCe = '$c_\mathrm{e}/c_\mathrm{t}$';

            for datIdx = 1:length(app.dat)
                dati = app.dat(datIdx);
                W = real(dati.w)*scaleW; 
                Ce = energyVelAxial(dati)*scaleCe;
                h = plot(ax, W(:), Ce(:), '.', 'SeriesIndex', datIdx, 'DisplayName',app.dat(datIdx).gew.family);
                app.hDisp(datIdx) = h; 
            end
            % ax.XLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringW;  ax.XLabel.Interpreter = 'latex';
            ax.YLabel.String = stringCe; ax.YLabel.Interpreter = 'latex';
            legend(ax); legend(ax,'Location','southeast','Interpreter','latex');
            
            selectMode(app, app.modeIdx.datIdx, app.modeIdx.sel)
            markMode(app); % mark mode
        end 

        function plotDispersionGroupvelAxial(app)
            ax = app.DispersionUIAxes; 
            scaleW = app.dat(1).gew.h/app.dat(1).gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';
            scaleCg = 1/app.dat(1).gew.lay{1}.mat.ct;
            stringCg = '$c_\mathrm{g}/c_\mathrm{t}$';

            for datIdx = 1:length(app.dat)
                dati = app.dat(datIdx);
                W = real(dati.w)*scaleW; 
                Cg = real( groupVelAxial(dati) )*scaleCg;
                h = plot(ax, W(:), Cg(:), '.', 'SeriesIndex', datIdx, 'DisplayName',app.dat(datIdx).gew.family);
                app.hDisp(datIdx) = h; 
            end
            % ax.XLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringW;  ax.XLabel.Interpreter = 'latex';
            ax.YLabel.String = stringCg; ax.YLabel.Interpreter = 'latex';
            legend(ax); legend(ax,'Location','southeast','Interpreter','latex');
            
            selectMode(app, app.modeIdx.datIdx, app.modeIdx.sel)
            markMode(app); % mark mode
        end 
        

        function dataTipString = onPointSelected(app, event)
            % define datatip string to display in dispersion curves:
            dataTipString = "";
            line = event.Target;
            datIdx = find(app.hDisp == line);
            retrievComps = datIdx ~= app.modeIdx.datIdx;
            if line.Parent == app.DispersionUIAxes
                % % Restart debounce timer
                % if strcmp(app.plotDelayTimer.Running,'on')
                %     stop(app.plotDelayTimer)
                % end
                % start(app.plotDelayTimer)

                selectMode(app,datIdx,event.DataIndex);
                fieldTypeIdx = app.DropDownModeField.Value; 
                fieldGetterFunction = app.fieldFunctionList{fieldTypeIdx}; % function to retrive the desired field (displacement, stress, etc)
                if retrievComps
                    components = app.interpolateField(fieldGetterFunction);
                    varName = app.fieldVariablenameList(fieldTypeIdx); 
                    nVars = size(app.modeField.value,2); % number of variables (call after interpolateField())
                    app.modeField.variableName = varName;
                    if nVars == 1 && varName ~= "u" % for scalars, don't add component index, except for displacement (uz)
                        app.modeField.componentNames = "$"+varName+"$"; 
                    else
                        app.modeField.componentNames = "$"+varName+"_{"+components+"}$"; 
                    end
                else % avoid retrieving the components again
                    app.interpolateField(fieldGetterFunction);
                end
                plotMode(app); % NOTE: plotMode() should only plot, not interpolate!
            elseif event.Target.Parent == app.ModeUIAxes
                val = event.Position(1); % x-coordinate on plot
                dataTipString = num2str(round(val,3));
            end
        end

        function componentNames = interpolateField(app, getField)
            dati = app.dat(app.modeIdx.datIdx);
            fieldData = getField(app.mode); % getField() is a function handle that retrieves the desired field (displacement, stress,...)
            % kz = real( sqrt((app.mode.w/dati.gew.lay{1}.mat.ct2)^2 - app.mode.k^2) ); % vertical wavenumber
            % Nlambda = ceil( dati.gew.lay{1}.h/(2*pi/kz) ); % number of wavelengths through the thickness of the layer
            % Ninterp = 30*Nlambda+1; if Ninterp < 201, Ninterp = 201; end
            Ninterp = 301;
            if strcmp(functions(getField).function,'poyntingVec')
                parity = ones(1,length(dati.gew.udof)); parity(dati.gew.dofOutofplane(dati.gew.udof)) = -1; 
                [ai, zi] = GEWinterpolate(dati.gew, fieldData, Ninterp, parity); % always even function 
            else
                [ai, zi] = GEWinterpolate(dati.gew, fieldData, Ninterp); % parity is guessed in GEWinterpolate
            end
            % zi = vertcat(dati.gew.geom.z{:});
            % ai = squeeze(cat(3,fieldData{:}));

            maxA = max(abs(ai), [], 'all');
            if abs(maxA) > 1e3*eps, ai = ai/maxA; end % only normalize if not (almost) zero
            s = size(ai); 
            app.modeField.value = reshape(ai, [s(1), prod(s(2:end))]); % size: length(zi) x number of components
            app.modeField.coord = zi;
            if nargout >= 1
                componentNames = app.getComponentNames(size(fieldData{1})); % return value
            end
        end

        function comps = getComponentNames(app, s)
            dati = app.dat(app.modeIdx.datIdx);
            s = s(4:end); % tensor components start at fourth dimension
            coord = dati.gew.coordNames; 
            comps = coord; % initialize with one component for each coordinate 
            isReduced = isempty(s) || any(s < length(coord)); 
            if isReduced % field has only the polarization components "udof"
                comps = coord(dati.gew.udof); % reduce also the component names
            end
            ndim = length(s); % rank of the tensor 
            if ndim == 2 % 2nd rank tensor
                comps = comps + comps.'; 
                comps = comps(:).'; 
            end
        end
               
        function plotModeComplex(app)
            % retrieve field and additional data
            gew = app.dat(app.modeIdx.datIdx).gew;
            ui = app.modeField.value; 
            zi = app.modeField.coord;

            % plot
            hold(app.ModeUIAxes, 'on');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            hr = plot(app.ModeUIAxes, real(ui), zi/gew.h, '-');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            hi = plot(app.ModeUIAxes, imag(ui), zi/gew.h, '--');
            app.hMode = [hr; hi];
            yline(app.ModeUIAxes, gew.geom.zItf(:)/gew.h, '-', 'HandleVisibility','off')
            xline(app.ModeUIAxes, 0, '-', 'HandleVisibility','off')
            xlabel(app.ModeUIAxes, "norm. "+app.DropDownModeField.Items(app.DropDownModeField.Value),'Interpreter','latex')
            coor = app.dat(1).gew.coordNames(3); 
            ylabel(app.ModeUIAxes, "coordinate $"+coor+"/h$",'Interpreter','latex')
            legend(app.ModeUIAxes, ["$\Re$ "+app.modeField.componentNames, "$\Im$ "+app.modeField.componentNames], 'Location','northeastoutside','Interpreter','latex');
            app.ModeUIAxes.XLim = [-1.01, 1.01];
        end

        function plotModeMagnitude(app)
            % retrieve field and additional data
            gew = app.dat(app.modeIdx.datIdx).gew;
            ui = app.modeField.value; 
            zi = app.modeField.coord;

            % plot
            hold(app.ModeUIAxes, 'on');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            h = plot(app.ModeUIAxes, abs(ui), zi/gew.h, '-');
            app.hMode = h; 
            yline(app.ModeUIAxes, gew.geom.zItf(:)/gew.h, '-', 'HandleVisibility','off')
            xline(app.ModeUIAxes, 0, '-', 'HandleVisibility','off')
            xlabel(app.ModeUIAxes, "norm. "+app.DropDownModeField.Items(app.DropDownModeField.Value),'Interpreter','latex')
            coor = app.dat(1).gew.coordNames(3); 
            ylabel(app.ModeUIAxes, "coordinate $"+coor+"/h$",'Interpreter','latex')
            legend(app.ModeUIAxes, "|"+app.modeField.componentNames+"|", 'Location','northeastoutside','Interpreter','latex');
            app.ModeUIAxes.XLim = [-0.01, 1.01];
        end

        function plotModeInstantaneous(app)
            % retrieve field and additional data
            gew = app.dat(app.modeIdx.datIdx).gew;
            w = app.mode.w; 
            t = app.time;
            ui = app.modeField.value.*exp(1i*w*t); 
            zi = app.modeField.coord;

            % plot
            hold(app.ModeUIAxes, 'on');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            hr = plot(app.ModeUIAxes, real(ui), zi/gew.h, '-');
            app.hMode = hr;
            yline(app.ModeUIAxes, gew.geom.zItf(:)/gew.h, '-', 'HandleVisibility','off')
            xline(app.ModeUIAxes, 0, '-', 'HandleVisibility','off')
            xlabel(app.ModeUIAxes, "norm. "+app.DropDownModeField.Items(app.DropDownModeField.Value),'Interpreter','latex')
            coor = app.dat(1).gew.coordNames(3); 
            ylabel(app.ModeUIAxes, "coordinate $"+coor+"/h$",'Interpreter','latex')
            legend(app.ModeUIAxes, app.modeField.componentNames, 'Location','northeastoutside','Interpreter','latex');
            app.ModeUIAxes.XLim = [-1.01, 1.01];
        end

        function plotModeWaveVecs(app)
            % extract mode: 
            mode = app.mode;
            ky = squeeze(mode.beta);
            kx = mode.k*ones(size(ky)); 

            % normalize:
            krLengths = sqrt(real(kx).^2 + real(ky).^2);
            kiLengths = sqrt(imag(kx).^2 + imag(ky).^2);
            k0 = max([krLengths; kiLengths]);
            kx = kx/k0; ky = ky/k0; % normalize

            at = []; 
            for loading = mode.gew.halfSpaces
                if loading.at == "bottom"
                    z = -0.55;
                else
                    z = 0.55; 
                end
                at = [at; ones(length(loading.dofA),1)*z];
            end 

            % move origin of wave vectors such that they do not overlap the plate:
            below =  at == -0.55; 
            above =  at ==  0.55; 
            upr    =  real(ky) >  1e-3*abs(real(kx));
            downr  =  real(ky) < -1e-3*abs(real(kx));
            upi    =  imag(ky) >  1e-3*abs(imag(kx));
            downi  =  imag(ky) < -1e-3*abs(imag(kx));
            if any(below & upr | below & upi) % plot all lower bulk wave vectors at same position
                upComps = [real(ky(below & upr)); imag(ky(below & upi))]; % all upwards-going real and imaginary ky
                at(below) = -0.55 - max(abs(upComps));
            end
            if any(above & downr | above & downi) % plot all upper bulk wave vectors at same position
                upComps = [real(ky(above & downr)); imag(ky(above & downi))]; % all downwards-going real and imaginary ky
                at(above) = 0.55 + max(abs(upComps)); 
            end
            
            % prepare quiver plot
            xx = 0;  zz = at;
            [X, Z] = meshgrid(xx, zz); 
            
            % plot
            plotModeComplex(app)
            hold(app.ModeUIAxes, 'on');
            quiver(app.ModeUIAxes, X,Z,real(kx),real(ky),'Color','#3B518B','LineWidth',1.5, 'DisplayName','$\Re \mathbf{k}$');
            quiver(app.ModeUIAxes, X,Z,imag(kx),imag(ky),'Color','#C75E78','LineWidth',1.5, 'DisplayName','$\Im \mathbf{k}$');
            axis(app.ModeUIAxes, 'equal')
            drawnow
            % 
            % axis(app.ModeUIAxes, 'off')
        end

        function plotDispersion(app)
            cla(app.DispersionUIAxes,'reset');
            app.DispersionUIAxes.ColorOrder = lines(4); % reset by: cla reset
            app.DispersionUIAxes.Box = 'on';
            hold(app.DispersionUIAxes,'on');
            app.DropDownDispersionPlot.Value(app);  % Value is a function handle to the selected plot type (defined in startupFcn())
            hold(app.DispersionUIAxes,'off');
        end

        function plotMode(app)
            cla(app.ModeUIAxes,'reset');
            app.ModeUIAxes.Box = 'on';
            if isnan(app.mode.k)
                text(app.ModeUIAxes, 0.5, 0.5, "nothing to show", "VerticalAlignment","middle","HorizontalAlignment","center","FontSize",15);
                return;
            end
            app.DropDownPlotType.Value(app);  % Value is a function handle to the selected plot type (defined in startupFcn())
        end

        function updateAnimationFrame(app)
            % extract mode :
            gew = app.dat.gew;
            mode = app.mode;
            w = mode.w; % angular frequency
            dt = 1/70*2*pi/w; % increment in physical time
            t = app.time; 

            nLines = size(app.modeField.value,2);
            if numel(app.hMode) > nLines
                hModeList = app.hMode(1:nLines); 
                hRemove = app.hMode(nLines+1:end); 
                delete(hRemove); 
                app.hMode = hModeList; 
            end

            for l = 1:numel(app.hMode)
                app.hMode(l).XData = real(app.modeField.value(:,l).*exp(1i*w*t));
            end
            app.time = t + dt;
            drawnow limitrate nocallbacks
        end

        function ret = isReal(app)
            ret = true;
            for dati = app.dat
                if ~isreal(dati.k)
                    ret = false;
                    return; 
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datIn)
            % save data: 
            app.dat = datIn; 
            % pre-select some mode:
            datIdx = 1; 
            sels = find(~isnan(app.dat(datIdx).k)); indsel = floor(length(sels)*1/5.33); % for selection of a data point
            app.modeIdx.datIdx = datIdx;
            app.modeIdx.sel = sels(indsel);  % row and col indices of the selected data point

            % do before calling DropDownDispersionPlotValueChanged():
            app.dcm = datacursormode(app.GEWinspectorUIFigure);
            set(app.dcm, "Enable", "on");
            app.dcm.UpdateFcn = @(obj,event) onPointSelected(app,event);
            app.modeField.componentNames = []; % is required before calling DropDownDispersionPlotValueChanged() further down

            app.DropDownDispersionPlot.Items = [ "wave numbers (real)", "phase velocity" ];
            app.DropDownDispersionPlot.ItemsData = { @plotDispersionWavenumbers, @plotDispersionPhasevel };
            if isReal(app) % real spectrum
                app.DropDownDispersionPlot.Items = [ app.DropDownDispersionPlot.Items, "group velocity (axial)" ]; 
                app.DropDownDispersionPlot.ItemsData = [ app.DropDownDispersionPlot.ItemsData, {@plotDispersionGroupvelAxial} ]; 
            else % complex valued spectrum
                app.DropDownDispersionPlot.Items = [ "wave numbers (complex)", app.DropDownDispersionPlot.Items ]; 
                app.DropDownDispersionPlot.ItemsData = [ {@plotDispersionWavenumbersComplex}, app.DropDownDispersionPlot.ItemsData ]; 
                app.DropDownDispersionPlot.Items = [ app.DropDownDispersionPlot.Items, "energy velocity (axial)" ]; 
                app.DropDownDispersionPlot.ItemsData = [ app.DropDownDispersionPlot.ItemsData, {@plotDispersionEnergyvelAxial} ]; 
            end

            app.DropDownModeField.Items = ["displacements", "stress", "strain", "power flux density"];
            app.fieldVariablenameList = ["u", "T", "S", "p"]; 
            app.fieldFunctionList = {@displacement, @stress, @strain, @poyntingVec};
            if any( arrayfun( @(d)d.gew.isPiezoelectric, app.dat ) )
                app.DropDownModeField.Items = [app.DropDownModeField.Items, "potential", "electric field", "electric flux density"];
                app.fieldVariablenameList   = [app.fieldVariablenameList,   "φ",          "E",              "D"]; 
                app.fieldFunctionList       = [app.fieldFunctionList,      { @potential,  @electricField,   @electricFluxDensity }]; 
            end
            app.DropDownModeField.ItemsData = 1:length(app.DropDownModeField.Items); % will be used to index the data. Do this after adding all Items
            
            app.DropDownPlotType.Items = ["complex", "magnitude", "instantaneous"];
            app.DropDownPlotType.ItemsData = {@plotModeComplex, @plotModeMagnitude, @plotModeInstantaneous};
            if isa(app.dat, 'GEWdatLeaky')
                app.DropDownPlotType.Items = [app.DropDownPlotType.Items, "wave vectors"];
                app.DropDownPlotType.ItemsData = [app.DropDownPlotType.ItemsData, {@plotModeWaveVecs}];
            end

            % setup defaults for graphics:
            set(app.GEWinspectorUIFigure, "defaultLineMarkerSize", 5);

            % app.plotDelayTimer = timer( ...
            %     'ExecutionMode','singleShot', ...
            %     'StartDelay',0.25, ...   % debounce delay (seconds)
            %     'TimerFcn',@(~,~)plotMode(app));

            app.AnimationTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...   % or 'fixedSpacing'
                    'Period', 0.05, ...                 % seconds between frames
                    'TimerFcn', @(~,~)updateAnimationFrame(app) ...
                );
            
            % initial plots:
            app.DropDownDispersionPlotValueChanged();    % initial dispersion plot + select and extract mode data
            app.DropDownModeFieldValueChanged();         % set app.handleModePlot
        end

        % Value changed function: DropDownDispersionPlot
        function DropDownDispersionPlotValueChanged(app, event)
            plotDispersion(app)
        end

        % Value changed function: DropDownModeField
        function DropDownModeFieldValueChanged(app, event)
            isAnimated = app.animateSwitch.Value;
            if isAnimated % temporarily stop animation if it is running
                app.animateSwitch.Value = false; 
                animateSwitchValueChanged(app); % stops the AnimationTimer among other things
            end
            fieldTypeIdx = app.DropDownModeField.Value; 
            fieldGetterFunction = app.fieldFunctionList{fieldTypeIdx}; % function to retrive the desired field (displacement, stress, etc)
            components = app.interpolateField(fieldGetterFunction); % also gets components -> only place where we apply the fieldGetterFunction
            varName = app.fieldVariablenameList(fieldTypeIdx); 
            nVars = size(app.modeField.value,2); % number of variables (call after interpolateField())
            app.modeField.variableName = varName;
            if nVars == 1 && varName ~= "u" % for scalars, don't add component index, except for displacement (uz)
                app.modeField.componentNames = "$"+varName+"$"; 
            else
                app.modeField.componentNames = "$"+varName+"_{"+components+"}$"; 
            end
            plotMode(app);
            if isAnimated % resume animation
                app.animateSwitch.Value = true; 
                animateSwitchValueChanged(app); % stops the AnimationTimer among other things
            end
        end

        % Value changed function: animateSwitch
        function animateSwitchValueChanged(app, event)
            isAnimate = app.animateSwitch.Value;
            if isAnimate
                app.DropDownPlotType.Value = @plotModeInstantaneous;
                app.DropDownPlotType.Enable = 'off'; 
                plotMode(app); 
                start(app.AnimationTimer);
                app.ModeUIAxes.XLim = [-1.01, 1.01];
            else
                app.DropDownPlotType.Enable = 'on'; 
                stop(app.AnimationTimer);
            end
        end

        % Close request function: GEWinspectorUIFigure
        function GEWinspectorUIFigureCloseRequest(app, event)
            if ~isempty(app.AnimationTimer) && isvalid(app.AnimationTimer) % IMPORTANT: otherwise matlab gets slow!
                stop(app.AnimationTimer)
                delete(app.AnimationTimer)
            end
            if ~isempty(app.plotDelayTimer) && isvalid(app.plotDelayTimer)
                stop(app.plotDelayTimer)
                delete(app.plotDelayTimer)
            end
            delete(app)
        end

        % Value changed function: DropDownPlotType
        function DropDownPlotTypeValueChanged(app, event)
            plotMode(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GEWinspectorUIFigure = uifigure('Visible', 'off');
            colormap(app.GEWinspectorUIFigure, 'parula');
            app.GEWinspectorUIFigure.Name = 'GEWinspector';
            app.GEWinspectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @GEWinspectorUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.GEWinspectorUIFigure);
            app.DispersionUIAxes = uiaxes(app.GridLayout);
            title(app.DispersionUIAxes, 'Dispersion')
            xlabel(app.DispersionUIAxes, 'X')
            ylabel(app.DispersionUIAxes, 'Y')
            zlabel(app.DispersionUIAxes, 'Z')
            colormap(app.DispersionUIAxes, 'parula')
            app.ModeUIAxes = uiaxes(app.GridLayout);
            title(app.ModeUIAxes, 'Mode')
            xlabel(app.ModeUIAxes, 'X')
            ylabel(app.ModeUIAxes, 'Y')
            zlabel(app.ModeUIAxes, 'Z')
            colormap(app.ModeUIAxes, 'parula')
            app.plotDropDownLabel = uilabel(app.GridLayout);
            app.plotDropDownLabel.Text = 'plot';
            app.DropDownDispersionPlot = uidropdown(app.GridLayout);
            app.DropDownDispersionPlot.Items = {'wave numbers', 'phase velocity'};
            app.DropDownDispersionPlot.ValueChangedFcn = createCallbackFcn(app, @DropDownDispersionPlotValueChanged, true);
            app.DropDownDispersionPlot.Value = 'wave numbers';
            app.plotDropDown_2Label = uilabel(app.GridLayout);
            app.plotDropDown_2Label.Text = 'plot';
            app.DropDownModeField = uidropdown(app.GridLayout);
            app.DropDownModeField.Items = {'wave vectors'};
            app.DropDownModeField.ItemsData = {'{@plotModeWaveVecs}'};
            app.DropDownModeField.ValueChangedFcn = createCallbackFcn(app, @DropDownModeFieldValueChanged, true);
            app.DropDownModeField.Value = '{@plotModeWaveVecs}';
            app.animateSwitchLabel = uilabel(app.GridLayout);
            app.animateSwitchLabel.Text = 'animate';
            app.animateSwitch = uiswitch(app.GridLayout, 'slider');
            app.animateSwitch.ItemsData = [false true];
            app.animateSwitch.ValueChangedFcn = createCallbackFcn(app, @animateSwitchValueChanged, true);
            app.animateSwitch.Tooltip = {'harmonic animation'};
            app.animateSwitch.Value = false;
            app.DropDownPlotType = uidropdown(app.GridLayout);
            app.DropDownPlotType.ValueChangedFcn = createCallbackFcn(app, @DropDownPlotTypeValueChanged, true);
            % Show the figure after all components are created
            app.GEWinspectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GEWinspector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GEWinspectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GEWinspectorUIFigure)
        end
    end
end